classdef Segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AdjustButton            matlab.ui.control.Button
        ConfirmButton           matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        OutputPathLabel         matlab.ui.control.Label
        InputPathLabel          matlab.ui.control.Label
        SelectOutputPathButton  matlab.ui.control.Button
        SelectInputPathButton   matlab.ui.control.Button
        EndEditField            matlab.ui.control.NumericEditField
        EndEditFieldLabel       matlab.ui.control.Label
        StartEditField          matlab.ui.control.NumericEditField
        StartEditFieldLabel     matlab.ui.control.Label
        SoundButton             matlab.ui.control.Button
        AudioSegmentationLabel  matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
        audioList
        inputPath
        outputPath
        index  
        tmp_wav  % tmp_wav is the un-changed version
        curr_wav % curr_wav is the cutted version 
        filename
        output_filename 
        fs
        time_start
        time_end
        
    end
    
    methods (Access = private)
%% ====================================================        
% estimating segementation point by thresholding 
        function time_cut = estimate(app)
            audio = abs(app.tmp_wav) ;
            audio_abs = abs(audio) ; 
            
            time_cut = find(audio_abs>0.8, 1, 'first') ;   
            time_cut = time_cut + 0.1*app.fs ; 

        end
    end
%% ====================================================    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.index = 0 ;     
            % init the file path 
            app.inputPath=pwd;
            app.outputPath=pwd;
            app.SelectInputPathButton.Text = app.inputPath ;            
            app.SelectOutputPathButton.Text = app.outputPath ;
            % load the wav file 
            app.audioList  = dir(strcat(app.inputPath,'\','*.wav')) ;
            assignin("base","audioList",app.audioList);
        end

        % Button pushed function: SelectInputPathButton
        function SelectInputPathButtonPushed(app, event)
            % change the file path 
            app.inputPath = uigetdir ; 
            figure(app.UIFigure);
            app.SelectInputPathButton.Text = app.inputPath ; 
            % load the wav file 
            app.audioList  = dir(strcat(app.inputPath,'\','*.wav')) ;
            assignin("base","audioList",app.audioList);
        end

        % Button pushed function: SelectOutputPathButton
        function SelectOutputPathButtonPushed(app, event)
            app.outputPath = uigetdir ; 
            figure(app.UIFigure);
            app.SelectOutputPathButton.Text = app.outputPath ;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.index = app.index + 1 ;
            app.filename = strcat(app.inputPath,'\',app.audioList(app.index).name) ;
            app.output_filename = strcat(app.outputPath,'\',app.audioList(app.index).name) ;
            [app.tmp_wav, app.fs] = audioread(app.filename);
            t = 1/app.fs:1/app.fs:length(app.tmp_wav)/app.fs ; 
            plot(app.UIAxes,t,app.tmp_wav)
            hold(app.UIAxes,"on") 
            time_cut = app.estimate();
            plot(app.UIAxes,time_cut/app.fs,0.5,"r*") ;
            hold(app.UIAxes,"off") 
            app.curr_wav = app.tmp_wav(time_cut:end) ;
            app.UIAxes.Title.String = strcat("audio signal",string(app.index)) ; 
        end

        % Button pushed function: SoundButton
        function SoundButtonPushed(app, event)
            sound (app.curr_wav,app.fs) ; 
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.index = app.index - 1 ;
            app.filename = strcat(app.inputPath,'\',app.audioList(app.index).name) ;
            app.output_filename = strcat(app.outputPath,'\',app.audioList(app.index).name) ;
            [app.tmp_wav, app.fs] = audioread(app.filename);
            t = 1/app.fs:1/app.fs:length(app.tmp_wav)/app.fs ; 
            plot(app.UIAxes,t,app.tmp_wav)
            hold(app.UIAxes,"on") 
            % estimate the segemantation point by setting threshold 
            time_cut = app.estimate();

            plot(app.UIAxes,time_cut/app.fs,0.5,"r*") ;
            hold(app.UIAxes,"off") 
            app.curr_wav = app.tmp_wav(time_cut:end) ;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % write the file 
            audiowrite(strcat(app.output_filename(1:end-4),"_output.wav"),app.curr_wav,app.fs)
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            % adjust the range manually 
            roi = drawpoint(app.UIAxes);
            app.time_start = roi.Position(1) ; 
            app.StartEditField.Value = app.time_start;
            roi = drawpoint(app.UIAxes);
            app.time_end = roi.Position(1) ; 
            app.EndEditField.Value = app.time_end;

            app.curr_wav=app.tmp_wav(round(app.time_start*app.fs):round(app.time_end*app.fs)) ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Signal ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.AudioSegmentationLabel = uilabel(app.UIFigure);
            app.AudioSegmentationLabel.Text = 'Audio Segmentation ';
            app.SoundButton = uibutton(app.UIFigure, 'push');
            app.SoundButton.ButtonPushedFcn = createCallbackFcn(app, @SoundButtonPushed, true);
            app.SoundButton.Text = 'Sound ';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End ';
            app.EndEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectInputPathButton = uibutton(app.UIFigure, 'push');
            app.SelectInputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputPathButtonPushed, true);
            app.SelectInputPathButton.Text = 'Select Input Path';
            app.SelectOutputPathButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputPathButtonPushed, true);
            app.SelectOutputPathButton.Text = 'Select Output Path';
            app.InputPathLabel = uilabel(app.UIFigure);
            app.InputPathLabel.Text = 'Input Path = ';
            app.OutputPathLabel = uilabel(app.UIFigure);
            app.OutputPathLabel.Text = 'Output Path = ';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.AdjustButton = uibutton(app.UIFigure, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end